classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image3                   matlab.ui.control.Image
        LightModeSwitch          matlab.ui.control.Switch
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        comp                     comp
        StandardDeviationSwitch  matlab.ui.control.Switch
        SelectPlotSwitch         matlab.ui.control.Switch
        ImportButton             matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Created in MATLAB R2022a v3
    % Giannis Kotsias - 2022

    properties (Access = private)
        Table       % Stores file's data
        year        % Stores file's years
        temperature % Stores file's temperature
        months      % Stores file's months
        annual      % Stores file's average temperature
        targetYear  % Focus year to zoom
        zoom        % Zoom percentage
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            clc;
            [file, path] = uigetfile('*.csv');
            filename = [path file];

            % if user chose cancel
            if file == 0
                warningMessage = sprintf('Warning: Cancelled file operation. File has not been imported\n');
                msgbox(warningMessage);
            % if file loaded succesfully
            elseif isfile(filename)
                % extract information from import file
                app.Table = readtable(filename, "VariableNamingRule","preserve");
                app.year = app.Table.Year;
                app.annual = app.Table{:, end};
                app.temperature = app.Table{:, 4:end-1};                
                % x axis is the month vector
                app.months = 1:1:12;
                
                % no need for default axis controls
                disableDefaultInteractivity(app.UIAxes);
                disableDefaultInteractivity(app.UIAxes2);

                % like a Initializer list - Constructor
                app.comp.YearValue = min(app.year) + (max(app.year)-min(app.year))/2;
                app.targetYear = app.comp.YearValue;
                app.zoom = 20;
                app.UIAxes.Visible = true;
                app.SelectPlotSwitch.Visible = true;
                
                % plot temp per month
                plot(app.UIAxes, app.months, app.temperature);
                app.UIAxes.Title.Color = [0.5 0.5 0.5];
                app.UIAxes2.Title.Color = [0.5 0.5 0.5];
            % else file has not been imported successfully
            else
                warningMessage = sprintf('Warning: file does not exist:\n%s', filename);
                uiwait(msgbox(warningMessage));
            end
        end

        % Value changed function: SelectPlotSwitch
        function SelectPlotSwitchValueChanged(app, event)
            value = app.SelectPlotSwitch.Value;
            % if user chose year average, hide axis1 and show axis2
            if strcmp(value, 'Year Average')
                app.UIAxes.Visible = false;
                cla(app.UIAxes);
                app.StandardDeviationSwitch.Visible = true;
                app.UIAxes2.Visible = true;
                if strcmp(app.StandardDeviationSwitch.Value, 'Normal')
                    app.UIAxes2.YLimMode = "auto";
                    app.UIAxes2.XGrid = "on";
                    app.UIAxes2.YGrid = "on";
                end
                % show the custom zooming component and plot into axis2
                app.comp.Visible = true;
                plot(app.UIAxes2, app.year, app.annual, '-o', "Color", 'red', 'LineWidth', 1.2);   
            % if value = monthly values, then hide axis2 and show axis1
            elseif strcmp(value, 'Monthly Values')
                cla(app.UIAxes2);
                app.UIAxes2.Visible = false;
                app.StandardDeviationSwitch.Visible = false;
                app.StandardDeviationSwitch.Value = "Normal";
                app.UIAxes.Visible = true;
                app.comp.Visible = false;
                plot(app.UIAxes, app.months, app.temperature);
            end
        end

        % Value changed function: StandardDeviationSwitch
        function StandardDeviationSwitchValueChanged(app, event)
            value = app.StandardDeviationSwitch.Value;
            % plot mean, mean+std, mean-std concurrently and 
            % color the area between them
            if strcmp(value, 'Standard Deviation')
               app.UIAxes2.XGrid = "off";
               app.UIAxes2.YGrid = "off";
               
                deviation = std(app.temperature, 0, 2);
                hold(app.UIAxes2);
                plot(app.UIAxes2, app.year, app.annual + deviation, 'g');
                plot(app.UIAxes2, app.year, app.annual - deviation, 'g');
                
                
                app.UIAxes2.YLim = [min(app.annual-deviation) max(app.annual+deviation)];
                area(app.UIAxes2, app.year, app.annual+deviation, 'FaceColor',[1 0.8 0.8],'EdgeColor',[0.9 0.7 0.7]);
                area(app.UIAxes2, app.year, app.annual-deviation, 'FaceColor','white', 'EdgeColor',[0.9 0.7 0.7]);
                
                plot(app.UIAxes2, app.year, app.annual, '-o', "Color", 'red', 'LineWidth', 1.5);
                hold(app.UIAxes2, "off");
                
            else
                cla(app.UIAxes2);
                plot(app.UIAxes2, app.year, app.annual, '-o', "Color", 'red', 'LineWidth', 1.5);
                app.UIAxes2.YLimMode = "auto";
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
            end         
        end

        % Callback function: comp
        function compYearChanged(app, event)
            % update target year and configure axis limits based current Zoom 
            app.targetYear = app.comp.YearValue;
            app.UIAxes2.XLim = [(app.targetYear-(100-app.zoom)) (app.targetYear+(100.001-app.zoom))];
        end

        % Callback function: comp
        function compZoomChanged(app, event)
            % update zoom percentage
            app.zoom = app.comp.ZoomValue;
            app.UIAxes2.XLim = [app.targetYear-(100-app.zoom) app.targetYear+(100.001-app.zoom)];
        end

        % Value changed function: LightModeSwitch
        function LightModeSwitchValueChanged(app, event)
            value = app.LightModeSwitch.Value;
            
            % dark mode
            if strcmp(value, '.')
                app.UIFigure.Color = 'black';  
                app.comp.BackgroundColor = 'black';
            % light mode
            else
                app.UIFigure.Color = 'white';        
                app.comp.BackgroundColor = 'white';
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature per Month Plot')
            xlabel(app.UIAxes, 'Months')
            ylabel(app.UIAxes, 'Temperature(^oC)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes.XTickLabel = {'Jan'; 'Feb'; 'Mar'; 'Apr'; 'May'; 'Jun'; 'Jul'; 'Aug'; 'Sep'; 'Oct'; 'Nov'; 'Dec'};
            colormap(app.UIAxes, 'jet')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Average Temperature per Year Plot')
            xlabel(app.UIAxes2, 'Year')
            ylabel(app.UIAxes2, 'Average Temperature(^oC)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLimitMethod = 'tight';
            colormap(app.UIAxes2, 'jet')
            app.UIAxes2.Visible = 'off';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = fullfile(pathToMLAPP, 'import_120260.png');
            app.ImportButton.Text = 'Import';
            app.SelectPlotSwitch = uiswitch(app.UIFigure, 'slider');
            app.SelectPlotSwitch.Items = {'Monthly Values', 'Year Average'};
            app.SelectPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @SelectPlotSwitchValueChanged, true);
            app.SelectPlotSwitch.Visible = 'off';
            app.SelectPlotSwitch.Value = 'Monthly Values';
            app.StandardDeviationSwitch = uiswitch(app.UIFigure, 'slider');
            app.StandardDeviationSwitch.Items = {'Normal', 'Standard Deviation'};
            app.StandardDeviationSwitch.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationSwitchValueChanged, true);
            app.StandardDeviationSwitch.Visible = 'off';
            app.StandardDeviationSwitch.Value = 'Normal';
            app.comp = comp(app.UIFigure);
            app.comp.YearChangedFcn = createCallbackFcn(app, @compYearChanged, true);
            app.comp.ZoomChangedFcn = createCallbackFcn(app, @compZoomChanged, true);
            app.comp.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'brightness.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'moon.png');
            app.LightModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightModeSwitch.Items = {'..', '.'};
            app.LightModeSwitch.ValueChangedFcn = createCallbackFcn(app, @LightModeSwitchValueChanged, true);
            app.LightModeSwitch.Value = '..';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '640px-Tesla_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end