classdef comp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Image          matlab.ui.control.Image
        ZoomSlider     matlab.ui.control.Slider
        YearLabel      matlab.ui.control.Label
        YearEditField  matlab.ui.control.NumericEditField
        EditButton     matlab.ui.control.StateButton
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        YearChanged
        ZoomChanged
    end

    properties (Access = public)
        YearValue double = 1;
        ZoomValue double = 20;
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditButton
        function EditButtonValueChanged(comp, event)
            value = comp.EditButton.Value;
             
            if value == true
                comp.YearEditField.Visible = true;
                comp.YearLabel.Visible = true;
                comp.ZoomSlider.Visible = true;
                comp.Image.Visible = true;
            else
                comp.YearEditField.Visible = false;
                comp.YearLabel.Visible = false;
                comp.ZoomSlider.Visible = false;
                comp.Image.Visible = false;
            end
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(comp, event)
            comp.YearValue = event.Source.Value;
            notify(comp, 'YearChanged');
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(comp, event)
            comp.ZoomValue = event.Source.Value;
            notify(comp, 'ZoomChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.ZoomSlider.Value = comp.ZoomValue;
            comp.YearEditField.Value = comp.YearValue;
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 116 105];
            comp.BackgroundColor = [1 1 1];
            comp.EditButton = uibutton(comp, 'state');
            comp.EditButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditButtonValueChanged, true);
            comp.EditButton.Icon = fullfile(pathToMLAPP, 'circle-compose-draw-edit-write-icon-728133.png');
            comp.EditButton.Text = 'Edit';
            comp.YearEditField = uieditfield(comp, 'numeric');
            comp.YearEditField.RoundFractionalValues = 'on';
            comp.YearEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YearEditFieldValueChanged, true);
            comp.YearEditField.Visible = 'off';
            comp.YearLabel = uilabel(comp);
            comp.YearLabel.Visible = 'off';
            comp.YearLabel.Text = 'Year';
            comp.ZoomSlider = uislider(comp);
            comp.ZoomSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ZoomSliderValueChanged, true);
            comp.ZoomSlider.Visible = 'off';
            comp.Image = uiimage(comp);
            comp.Image.Visible = 'off';
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'pngwing.com.png');
        end
    end
end